classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        Label_3           matlab.ui.control.Label
        ListBox           matlab.ui.control.ListBox
        stopButton        matlab.ui.control.Button
        startpauseButton  matlab.ui.control.Button
        Panel             matlab.ui.container.Panel
        Label             matlab.ui.control.Label
        Knob              matlab.ui.control.Knob
        Knob_2Label       matlab.ui.control.Label
        Knob_2            matlab.ui.control.Knob
        Label_4           matlab.ui.control.Label
        ListBox_2         matlab.ui.control.ListBox
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        Label_5           matlab.ui.control.Label
        Spinner           matlab.ui.control.Spinner
        Tab_2             matlab.ui.container.Tab
        kSlider_4Label    matlab.ui.control.Label
        kSlider_4         matlab.ui.control.Slider
        kSlider_3Label    matlab.ui.control.Label
        kSlider_3         matlab.ui.control.Slider
        kSlider_2Label    matlab.ui.control.Label
        kSlider_2         matlab.ui.control.Slider
        kSliderLabel      matlab.ui.control.Label
        kSlider           matlab.ui.control.Slider
        Label_10          matlab.ui.control.Label
        Slider_5          matlab.ui.control.Slider
        Label_7           matlab.ui.control.Label
        Slider_6          matlab.ui.control.Slider
        Label_6           matlab.ui.control.Label
        Slider_7          matlab.ui.control.Slider
        Label_8           matlab.ui.control.Label
        Slider_8          matlab.ui.control.Slider
        Label_9           matlab.ui.control.Label
        Slider_9          matlab.ui.control.Slider
        kSlider_5Label    matlab.ui.control.Label
        kSlider_5         matlab.ui.control.Slider
        Label_11          matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes5           matlab.ui.control.UIAxes
        UIAxes6           matlab.ui.control.UIAxes
        UIAxes7           matlab.ui.control.UIAxes
    end

    properties (Access = private)
        player = 0;
        fs = 44100;
        yupper;
        c;
        f; %频谱图的横坐标
        p1; %频谱图单侧频谱
        resume;
        music;
        Long;
        volume;
        state = 0; % 0:停止； 1:播放； 2:暂停
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startpauseButton
        function startpauseButtonPushed(app, event)
            if strcmp(app.ListBox.Value,'请选择') == 1
                errordlg('请选择歌曲');
            elseif strcmp(app.ListBox_2.Value,'请选择') == 1
                errordlg('请选择乐器');
            elseif app.Knob.Value == 0
                errordlg('请提高音量');
            else
                if app.state == 0
                    app.state = 1;
                    app.resume = 1;
                    [M, L] = digit_music_gene(app.ListBox.Value, round(app.Knob_2.Value+0.1));
                    for head = app.resume:length(M)
                        t = clock;%计算运行时间
                        if app.state == 2
                            app.resume = head;
                            app.music = M;
                            app.Long = L;
                            break;
                        elseif app.state == 0
                            break;
                        end
                        s = generate_tone(M(head),L(head)/app.Spinner.Value,app.fs,round(app.Knob_2.Value+0.1),app.yupper,app.c);
                        s = 10^(app.volume/10)*s;
                        plot(app.UIAxes4, s);%滤波前
                        l = length(s);
                        p2 = abs(fft(s)/l);
                        p = p2(1:l/2+1);
                        fre = app.fs*(0:(l/2))/l;
                        plot(app.UIAxes6, fre, p);
                        
                        
                        %均衡器
                        lengthData = length(s);
                        freq = 0:(app.fs/lengthData):((lengthData-1)*app.fs/lengthData); %fft可以得到的频率有这些，单位：Hz
                        freq_points = [31 62 125 250 500 1000 2000 4000 8000 16000];%均衡器频点
                        freq_points_gain = [app.Slider_5.Value, app.Slider_6.Value, app.Slider_7.Value, app.Slider_8.Value, app.Slider_9.Value, app.kSlider.Value, app.kSlider_2.Value, app.kSlider_3.Value, app.kSlider_4.Value, app.kSlider_5.Value];
                        %频点处的增益(dB)
                        freq_gain = interp1(freq_points, freq_points_gain, freq, 'makima'); %对增益插值
                        freq_gain = 10.^(freq_gain/10); % 音频中dB=10logA/B
                        s_fft = fft(s);
                        s_response = s_fft.*freq_gain; %对应频率乘以增益
                        s = real(ifft(s_response)); %逆变换，取实部
                        plot(app.UIAxes5, s);%滤波后
                        l = length(s);
                        p2 = abs(fft(s)/l);
                        p = p2(1:l/2+1);
                        fre = app.fs*(0:(l/2))/l;
                        plot(app.UIAxes7, fre, p);
                        
                        sound(s, app.fs);
                        time = L(head)/app.Spinner.Value;
                        pause(time - (clock-t));%等待播放完成(播放所需时间-程序运行时间)
                    end
                    if head == length(M)
                        app.state = 0;
                    end
                elseif app.state == 1
                    app.state = 2;
                elseif app.state == 2
                    app.state = 1;
                    plot(app.UIAxes, app.yupper);
                    plot(app.UIAxes2, app.f, app.p1);
                    M = app.music;
                    L = app.Long;
                    for head = app.resume:length(M)
                        t = clock;%计算运行时间
                        if app.state == 2
                            app.resume = head;
                            app.music = M;
                            app.Long = L;
                            break;
                        elseif app.state == 0
                            break;
                        end
                        s = generate_tone(M(head),L(head)/app.Spinner.Value,app.fs,round(app.Knob_2.Value+0.1),app.yupper,app.c);
                        plot(app.UIAxes4, s);%滤波前
                        l = length(s);
                        p2 = abs(fft(s)/l);
                        p = p2(1:l/2+1);
                        fre = app.fs*(0:(l/2))/l;
                        plot(app.UIAxes6, fre, p);
                        
                        
                        %均衡器
                        lengthData = length(s);
                        freq = 0:(app.fs/lengthData):((lengthData-1)*app.fs/lengthData); %fft可以得到的频率有这些，单位：Hz
                        freq_points = [31 62 125 250 500 1000 2000 4000 8000 16000];%均衡器频点
                        freq_points_gain = [app.Slider_5.Value, app.Slider_6.Value, app.Slider_7.Value, app.Slider_8.Value, app.Slider_9.Value, app.kSlider.Value, app.kSlider_2.Value, app.kSlider_3.Value, app.kSlider_4.Value, app.kSlider_5.Value];
                        %频点处的增益(dB)
                        freq_gain = interp1(freq_points, freq_points_gain, freq, 'makima'); %对增益插值
                        freq_gain = 10.^(freq_gain/10); % 音频中dB=10logA/B
                        s_fft = fft(s);
                        s_response = s_fft.*freq_gain; %对应频率乘以增益
                        s = real(ifft(s_response)); %逆变换，取实部
                        plot(app.UIAxes5, s);%滤波后
                        l = length(s);
                        p2 = abs(fft(s)/l);
                        p = p2(1:l/2+1);
                        fre = app.fs*(0:(l/2))/l;
                        plot(app.UIAxes7, fre, p);
                        
                        s = 10^(app.volume/10)*s;
                        sound(s, app.fs);
                        time = L(head)/app.Spinner.Value;
                        pause(time - (clock-t));%等待播放完成(播放所需时间-程序运行时间)
                    end
                    if head == length(M)
                        app.state = 0;
                    end
                end
            end
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            value = app.Knob_2.Value;
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            app.state = 0;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.state = 0;
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            [app.yupper, app.c, app.f, app.p1] = get_envelope(value);
            plot(app.UIAxes, app.yupper);
            plot(app.UIAxes2, app.f, app.p1);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
        end

        % Value changed function: Slider_5
        function Slider_31ValueChanged(app, event)
            value = app.Slider_5.Value;
            
        end

        % Value changed function: Slider_6
        function Slider_62ValueChanged(app, event)
            value = app.Slider_6.Value;
            
        end

        % Value changed function: Slider_7
        function Slider_125ValueChanged(app, event)
            value = app.Slider_7.Value;
            
        end

        % Value changed function: Slider_8
        function Slider_250ValueChanged(app, event)
            value = app.Slider_8.Value;
            
        end

        % Value changed function: Slider_9
        function Slider_500ValueChanged(app, event)
            value = app.Slider_9.Value;
            
        end

        % Value changed function: kSlider
        function Slider_1kValueChanged(app, event)
            value = app.kSlider.Value;
            
        end

        % Value changed function: kSlider_2
        function Slider_2kValueChanged(app, event)
            value = app.kSlider_2.Value;
            
        end

        % Value changed function: kSlider_3
        function Slider_4kValueChanged(app, event)
            value = app.kSlider_3.Value;
            
        end

        % Value changed function: kSlider_4
        function Slider_8kValueChanged(app, event)
            value = app.kSlider_4.Value;
            
        end

        % Value changed function: kSlider_5
        function Slider_16kValueChanged(app, event)
            value = app.kSlider_5.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged2(app, event)
            value = app.DropDown.Value;
            switch value
                case '无'
                    app.Slider_5.Value = 0;
                    app.Slider_6.Value = 0;
                    app.Slider_7.Value = 0;
                    app.Slider_8.Value = 0;
                    app.Slider_9.Value = 0;
                    app.kSlider.Value = 0;
                    app.kSlider_2.Value = 0;
                    app.kSlider_3.Value = 0;
                    app.kSlider_4.Value = 0;
                    app.kSlider_5.Value = 0;
                case '古典'
                    app.Slider_5.Value = 4;
                    app.Slider_6.Value = 4;
                    app.Slider_7.Value = 3;
                    app.Slider_8.Value = 2;
                    app.Slider_9.Value = -1;
                    app.kSlider.Value = -1;
                    app.kSlider_2.Value = 0;
                    app.kSlider_3.Value = 1;
                    app.kSlider_4.Value = 3;
                    app.kSlider_5.Value = 4;
                case '流行'
                    app.Slider_5.Value = -1;
                    app.Slider_6.Value = -1;
                    app.Slider_7.Value = 0;
                    app.Slider_8.Value = 1;
                    app.Slider_9.Value = 4;
                    app.kSlider.Value = 3;
                    app.kSlider_2.Value = 1;
                    app.kSlider_3.Value = 0;
                    app.kSlider_4.Value = -1;
                    app.kSlider_5.Value = 1;
                case '爵士'
                    app.Slider_5.Value = 3;
                    app.Slider_6.Value = 3;
                    app.Slider_7.Value = 1;
                    app.Slider_8.Value = 2;
                    app.Slider_9.Value = -1;
                    app.kSlider.Value = -1;
                    app.kSlider_2.Value = 0;
                    app.kSlider_3.Value = 1;
                    app.kSlider_4.Value = 2;
                    app.kSlider_5.Value = 4;
            end
            freq_points = [31, 62, 125, 250, 500, 1000, 2000, 4000, 8000, 16000];
            freq_points_gain = [app.Slider_5.Value, app.Slider_6.Value, app.Slider_7.Value, app.Slider_8.Value, app.Slider_9.Value, app.kSlider.Value, app.kSlider_2.Value, app.kSlider_3.Value, app.kSlider_4.Value, app.kSlider_5.Value];
            freq = linspace(0, 16000, 500);
            freq_gain = interp1(freq_points, freq_points_gain, freq, 'makima'); %对增益插值
            plot(app.UIAxes3, freq, freq_gain);
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            app.volume = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '主界面';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '播放列表';
            app.ListBox = uilistbox(app.Tab);
            app.ListBox.Items = {'请选择', '少年锦时', '起风了', '乐器声复现'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '请选择';
            app.stopButton = uibutton(app.Tab, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.startpauseButton = uibutton(app.Tab, 'push');
            app.startpauseButton.ButtonPushedFcn = createCallbackFcn(app, @startpauseButtonPushed, true);
            app.startpauseButton.Text = 'start/pause';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = 'Panel';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '音量';
            app.Knob = uiknob(app.Panel, 'continuous');
            app.Knob.Limits = [0 50];
            app.Knob.MajorTickLabels = {''};
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Knob_2Label = uilabel(app.Panel);
            app.Knob_2Label.Text = '音调';
            app.Knob_2 = uiknob(app.Panel, 'continuous');
            app.Knob_2.Limits = [1 7];
            app.Knob_2.MajorTicks = [1 2 3 4 5 6 7];
            app.Knob_2.MajorTickLabels = {'toneA', 'toneB', 'toneC', 'toneD', 'toneE', 'toneF', 'toneG', ''};
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_2.MinorTicks = [1 2 3 4 5 6 7];
            app.Knob_2.Value = 1;
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '乐器';
            app.ListBox_2 = uilistbox(app.Tab);
            app.ListBox_2.Items = {'请选择', '钢琴', '吉他', '萨克斯', '铜管乐', '弦乐', '小提琴'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = '请选择';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '包络图')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, '频谱图')
            xlabel(app.UIAxes2, 'f(Hz)')
            ylabel(app.UIAxes2, '|p1(f)|')
            app.UIAxes2.XLim = [0 3000];
            app.Label_5 = uilabel(app.Tab);
            app.Label_5.Text = '倍速';
            app.Spinner = uispinner(app.Tab);
            app.Spinner.Step = 0.25;
            app.Spinner.Limits = [0.25 2];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '均衡器';
            app.kSlider_4Label = uilabel(app.Tab_2);
            app.kSlider_4Label.Text = '8k';
            app.kSlider_4 = uislider(app.Tab_2);
            app.kSlider_4.Limits = [-12 12];
            app.kSlider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_8kValueChanged, true);
            app.kSlider_3Label = uilabel(app.Tab_2);
            app.kSlider_3Label.Text = '4k';
            app.kSlider_3 = uislider(app.Tab_2);
            app.kSlider_3.Limits = [-12 12];
            app.kSlider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_4kValueChanged, true);
            app.kSlider_2Label = uilabel(app.Tab_2);
            app.kSlider_2Label.Text = '2k';
            app.kSlider_2 = uislider(app.Tab_2);
            app.kSlider_2.Limits = [-12 12];
            app.kSlider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2kValueChanged, true);
            app.kSliderLabel = uilabel(app.Tab_2);
            app.kSliderLabel.Text = '1k';
            app.kSlider = uislider(app.Tab_2);
            app.kSlider.Limits = [-12 12];
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_1kValueChanged, true);
            app.Label_10 = uilabel(app.Tab_2);
            app.Label_10.Text = '31';
            app.Slider_5 = uislider(app.Tab_2);
            app.Slider_5.Limits = [-12 12];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_31ValueChanged, true);
            app.Label_7 = uilabel(app.Tab_2);
            app.Label_7.Text = '62';
            app.Slider_6 = uislider(app.Tab_2);
            app.Slider_6.Limits = [-12 12];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_62ValueChanged, true);
            app.Label_6 = uilabel(app.Tab_2);
            app.Label_6.Text = '125';
            app.Slider_7 = uislider(app.Tab_2);
            app.Slider_7.Limits = [-12 12];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_125ValueChanged, true);
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '250';
            app.Slider_8 = uislider(app.Tab_2);
            app.Slider_8.Limits = [-12 12];
            app.Slider_8.ValueChangedFcn = createCallbackFcn(app, @Slider_250ValueChanged, true);
            app.Label_9 = uilabel(app.Tab_2);
            app.Label_9.Text = '500';
            app.Slider_9 = uislider(app.Tab_2);
            app.Slider_9.Limits = [-12 12];
            app.Slider_9.ValueChangedFcn = createCallbackFcn(app, @Slider_500ValueChanged, true);
            app.kSlider_5Label = uilabel(app.Tab_2);
            app.kSlider_5Label.Text = '16k';
            app.kSlider_5 = uislider(app.Tab_2);
            app.kSlider_5.Limits = [-12 12];
            app.kSlider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_16kValueChanged, true);
            app.Label_11 = uilabel(app.Tab_2);
            app.Label_11.Text = '音乐风格选择';
            app.DropDown = uidropdown(app.Tab_2);
            app.DropDown.Items = {'无', '古典', '流行', '爵士'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged2, true);
            app.DropDown.Value = '无';
            app.UIAxes3 = uiaxes(app.Tab_2);
            title(app.UIAxes3, '均衡器频谱增益图')
            xlabel(app.UIAxes3, 'f(Hz)')
            ylabel(app.UIAxes3, '增益(dB)')
            app.UIAxes3.XLim = [0 16000];
            app.UIAxes3.YLim = [-12 12];
            app.UIAxes3.YTick = [-12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12];
            app.UIAxes4 = uiaxes(app.Tab_2);
            title(app.UIAxes4, '滤波前(时域)')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.Tab_2);
            title(app.UIAxes5, '滤波后(时域)')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes6 = uiaxes(app.Tab_2);
            title(app.UIAxes6, '滤波前(频域)')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.UIAxes6.XLim = [0 5000];
            app.UIAxes7 = uiaxes(app.Tab_2);
            title(app.UIAxes7, '滤波后(频域)')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes7.XLim = [0 5000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end