classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label              matlab.ui.control.Label
        ListBox            matlab.ui.control.ListBox
        stopButton         matlab.ui.control.Button
        Label_3            matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        Label_4            matlab.ui.control.Label
        Knob               matlab.ui.control.Knob
        startresumeButton  matlab.ui.control.Button
        kLabel             matlab.ui.control.Label
        kSlider            matlab.ui.control.Slider
        kLabel_2           matlab.ui.control.Label
        kSlider_2          matlab.ui.control.Slider
        kLabel_3           matlab.ui.control.Label
        kSlider_3          matlab.ui.control.Slider
        kLabel_4           matlab.ui.control.Label
        kSlider_4          matlab.ui.control.Slider
        SliderLabel        matlab.ui.control.Label
        Slider             matlab.ui.control.Slider
        Slider_2Label      matlab.ui.control.Label
        Slider_2           matlab.ui.control.Slider
        Slider_3Label      matlab.ui.control.Label
        Slider_3           matlab.ui.control.Slider
        Slider_4Label      matlab.ui.control.Label
        Slider_4           matlab.ui.control.Slider
        Slider_5Label      matlab.ui.control.Label
        Slider_5           matlab.ui.control.Slider
        kSlider_5Label     matlab.ui.control.Label
        kSlider_5          matlab.ui.control.Slider
        UIAxes             matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_4           matlab.ui.control.UIAxes
        Label_5            matlab.ui.control.Label
        Switch             matlab.ui.control.Switch
        Label_8            matlab.ui.control.Label
        Switch_2           matlab.ui.control.Switch
        Knob_2Label        matlab.ui.control.Label
        Knob_3             matlab.ui.control.Knob
        Knob_3Label        matlab.ui.control.Label
        Knob_2             matlab.ui.control.Knob
    end

    
    properties (Access = private)
        filename; % Description
        y;
        L;
        Fs;
        y_de;
        resume;
        state = 0; % 0:停止，1：播放，2：暂停
        volume;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startresumeButton
        function startresumeButtonPushed(app, event)
            if app.state == 0
                app.state = 1;
                app.resume = 1;
                [app.y, app.Fs] = audioread([app.filename '.wav']);
                [app.y_de, ~] = audioread([app.filename '消声版.wav']);
                app.L = length(app.y);
                step = app.Fs;
                for head = app.resume:step:app.L
                    t = clock; %计算时间
                    if app.state == 0
                        break;
                    elseif app.state == 2
                        app.resume = head;
                        break;
                    end
                    bottom = min(head + step - 1, app.L);
                    
                    if strcmp(app.Switch.Value, 'Off')
                        s = app.y(head:bottom);
                    else
                        s = app.y_de(head:bottom);
                    end
                    
                    lengthData = length(s);
                    x = 0:1/app.Fs:(lengthData-1)/app.Fs;
                    plot(app.UIAxes, x, s);%滤波前
                    p2 = abs(fft(s)/lengthData);
                    p = p2(1:lengthData/2+1);
                    fre = app.Fs*(0:(lengthData/2))/lengthData;
                    plot(app.UIAxes_3, fre, p);
                    
                    freq_points_gain = [app.Slider.Value, app.Slider_2.Value, app.Slider_3.Value, app.Slider_4.Value, app.Slider_5.Value, app.kSlider.Value, app.kSlider_2.Value, app.kSlider_3.Value, app.kSlider_4.Value, app.kSlider_5.Value];
                    if strcmp(app.Switch_2.Value, '频域')
                        %均衡器
                        freq = 0:(app.Fs/lengthData):((lengthData-1)*app.Fs/lengthData); %fft可以得到的频率有这些，单位：Hz
                        freq_points = [31 62 125 250 500 1000 2000 4000 8000 16000];%均衡器频点
                        %频点处的增益(dB)
                        freq_gain = interp1(freq_points, freq_points_gain, freq, 'makima'); %对增益插值
                        freq_gain = 10.^(freq_gain/10); % 音频中dB=10logA/B
                        s_fft = fft(s);
                        s_response = s_fft.*freq_gain; %对应频率乘以增益
                        s = real(ifft(s_response)); %逆变换，取实部
                    else
                        s = time_domain(s, app.Fs, freq_points_gain);
                    end
                    
                    plot(app.UIAxes_2, x, s);%滤波后
                    p2 = abs(fft(s)/lengthData);
                    p = p2(1:lengthData/2+1);
                    fre = app.Fs*(0:(lengthData/2))/lengthData;
                    plot(app.UIAxes_4, fre, p);
                    
                    s = 10^(app.volume/10)*s;
                    if app.Knob_3.Value~=1
                        s = change_tone(s,app.Knob_3.Value);
                    end
                    if app.Knob_2.Value~=1
                        s = wsola(s, 1/app.Knob_2.Value);
                    end
                    sound(s,app.Fs);
                    
                    time = length(s)/app.Fs - (clock-t);
                    if app.Knob_2.Value>1
                        pause((time-0.1)/app.Knob_2.Value);
                    else
                     pause(time-0.1);
                    end
                end
                
                if bottom == app.L
                    app.state = 0;
                end
            elseif app.state == 1
                app.state = 2;
            elseif app.state == 2
                app.state = 1;
                step = app.Fs;
                for head = app.resume:step:app.L
                    t = clock; %计算时间
                    if app.state == 0
                        break;
                    elseif app.state == 2
                        app.resume = head;
                        break;
                    end
                    bottom = min(head + step - 1, app.L);
                    
                    if strcmp(app.Switch.Value, 'Off')
                        s = app.y(head:bottom);
                    else
                        s = app.y_de(head:bottom);
                    end
                    
                    lengthData = length(s);
                    x = 0:1/app.Fs:(lengthData-1)/app.Fs;
                    plot(app.UIAxes, x, s);%滤波前
                    p2 = abs(fft(s)/lengthData);
                    p = p2(1:lengthData/2+1);
                    fre = app.Fs*(0:(lengthData/2))/lengthData;
                    plot(app.UIAxes_3, fre, p);
                    
                    freq_points_gain = [app.Slider.Value, app.Slider_2.Value, app.Slider_3.Value, app.Slider_4.Value, app.Slider_5.Value, app.kSlider.Value, app.kSlider_2.Value, app.kSlider_3.Value, app.kSlider_4.Value, app.kSlider_5.Value];
                    if strcmp(app.Switch_2.Value, '频域')
                        %均衡器
                        freq = 0:(app.Fs/lengthData):((lengthData-1)*app.Fs/lengthData); %fft可以得到的频率有这些，单位：Hz
                        freq_points = [31 62 125 250 500 1000 2000 4000 8000 16000];%均衡器频点
                        %频点处的增益(dB)
                        freq_gain = interp1(freq_points, freq_points_gain, freq, 'makima'); %对增益插值
                        freq_gain = 10.^(freq_gain/10); % 音频中dB=10logA/B
                        s_fft = fft(s);
                        s_response = s_fft.*freq_gain; %对应频率乘以增益
                        s = real(ifft(s_response)); %逆变换，取实部
                    else
                        s = time_domain(s, app.Fs, freq_points_gain);
                    end
                    
                    plot(app.UIAxes_2, x, s);%滤波后
                    p2 = abs(fft(s)/lengthData);
                    p = p2(1:lengthData/2+1);
                    fre = app.Fs*(0:(lengthData/2))/lengthData;
                    plot(app.UIAxes_4, fre, p);
                    
                    s = 10^(app.volume/10)*s;
                    if app.Knob_3.Value~=1
                        s = change_tone(s,app.Knob_3.Value);
                    end
                    if app.Knob_2.Value~=1
                        s = wsola(s, 1/app.Knob_2.Value);
                    end
                    
                    sound(s,app.Fs);
                    
                    time = length(s)/app.Fs - (clock-t);
                    if app.Knob_2.Value>1
                        pause((time-0.1)/app.Knob_2.Value);
                    else
                     pause(time-0.1);
                    end
                    
                end
                if bottom == app.L
                    app.state = 0;
                end
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.filename = value;
            app.state = 0;
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            app.volume = changingValue;
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            app.state = 0;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            
        end

        % Value changed function: kSlider
        function kSliderValueChanged(app, event)
            value = app.kSlider.Value;
            
        end

        % Value changed function: kSlider_2
        function kSlider_2ValueChanged(app, event)
            value = app.kSlider_2.Value;
            
        end

        % Value changed function: kSlider_3
        function kSlider_3ValueChanged(app, event)
            value = app.kSlider_3.Value;
            
        end

        % Value changed function: kSlider_4
        function kSlider_4ValueChanged(app, event)
            value = app.kSlider_4.Value;
            
        end

        % Value changed function: kSlider_5
        function kSlider_5ValueChanged(app, event)
            value = app.kSlider_5.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case '无'
                    app.Slider.Value = 0;
                    app.Slider_2.Value = 0;
                    app.Slider_3.Value = 0;
                    app.Slider_4.Value = 0;
                    app.Slider_5.Value = 0;
                    app.kSlider.Value = 0;
                    app.kSlider_2.Value = 0;
                    app.kSlider_3.Value = 0;
                    app.kSlider_4.Value = 0;
                    app.kSlider_5.Value = 0;
                case '古典'
                    app.Slider.Value = 4;
                    app.Slider_2.Value = 4;
                    app.Slider_3.Value = 3;
                    app.Slider_4.Value = 2;
                    app.Slider_5.Value = -1;
                    app.kSlider.Value = -1;
                    app.kSlider_2.Value = 0;
                    app.kSlider_3.Value = 1;
                    app.kSlider_4.Value = 3;
                    app.kSlider_5.Value = 4;
                case '流行'
                    app.Slider.Value = -1;
                    app.Slider_2.Value = -1;
                    app.Slider_3.Value = 0;
                    app.Slider_4.Value = 1;
                    app.Slider_5.Value = 4;
                    app.kSlider.Value = 3;
                    app.kSlider_2.Value = 1;
                    app.kSlider_3.Value = 0;
                    app.kSlider_4.Value = -1;
                    app.kSlider_5.Value = 1;
                case '爵士'
                    app.Slider.Value = 3;
                    app.Slider_2.Value = 3;
                    app.Slider_3.Value = 1;
                    app.Slider_4.Value = 2;
                    app.Slider_5.Value = -1;
                    app.kSlider.Value = -1;
                    app.kSlider_2.Value = 0;
                    app.kSlider_3.Value = 1;
                    app.kSlider_4.Value = 2;
                    app.kSlider_5.Value = 4;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            disp(class(value));
        end

        % Callback function
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '播放列表';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'无', '周深 - 大鱼', 'Item 2', 'Item 3', 'Item 4'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '无';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '音乐风格';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'无', '古典', '流行', '爵士'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '无';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '音量';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 10];
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.startresumeButton = uibutton(app.UIFigure, 'push');
            app.startresumeButton.ButtonPushedFcn = createCallbackFcn(app, @startresumeButtonPushed, true);
            app.startresumeButton.Text = 'start/resume';
            app.kLabel = uilabel(app.UIFigure);
            app.kLabel.Text = '1k';
            app.kSlider = uislider(app.UIFigure);
            app.kSlider.Limits = [-12 12];
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.kLabel_2 = uilabel(app.UIFigure);
            app.kLabel_2.Text = '2k';
            app.kSlider_2 = uislider(app.UIFigure);
            app.kSlider_2.Limits = [-12 12];
            app.kSlider_2.ValueChangedFcn = createCallbackFcn(app, @kSlider_2ValueChanged, true);
            app.kLabel_3 = uilabel(app.UIFigure);
            app.kLabel_3.Text = '4k';
            app.kSlider_3 = uislider(app.UIFigure);
            app.kSlider_3.Limits = [-12 12];
            app.kSlider_3.ValueChangedFcn = createCallbackFcn(app, @kSlider_3ValueChanged, true);
            app.kLabel_4 = uilabel(app.UIFigure);
            app.kLabel_4.Text = '8k';
            app.kSlider_4 = uislider(app.UIFigure);
            app.kSlider_4.Limits = [-12 12];
            app.kSlider_4.ValueChangedFcn = createCallbackFcn(app, @kSlider_4ValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '31';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-12 12];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '62';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-12 12];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = '125';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-12 12];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = '250';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-12 12];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = '500';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [-12 12];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.kSlider_5Label = uilabel(app.UIFigure);
            app.kSlider_5Label.Text = '16k';
            app.kSlider_5 = uislider(app.UIFigure);
            app.kSlider_5.Limits = [-12 12];
            app.kSlider_5.ValueChangedFcn = createCallbackFcn(app, @kSlider_5ValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '滤波前（时域）')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '滤波后（时域）')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XLim = [0 1];
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '滤波前(频域)')
            xlabel(app.UIAxes_3, 'f(Hz)')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_3.XLim = [0 5000];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '滤波后(频域)')
            xlabel(app.UIAxes_4, 'f(Hz)')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_4.XLim = [0 5000];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '人声消除';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '滤波方式';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'频域', '时域'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = '频域';
            app.Knob_2Label = uilabel(app.UIFigure);
            app.Knob_2Label.Text = '音调';
            app.Knob_3 = uiknob(app.UIFigure, 'continuous');
            app.Knob_3.Limits = [0.5 2];
            app.Knob_3.MajorTicks = [0.5 0.75 1 1.25 1.5 2];
            app.Knob_3.MajorTickLabels = {'很高', '较高', '正常', '较低', '很低', '非常低'};
            app.Knob_3.MinorTicks = [0.5 0.75 1 1.25 1.5 2];
            app.Knob_3.Value = 1;
            app.Knob_3Label = uilabel(app.UIFigure);
            app.Knob_3Label.Text = '播放速度';
            app.Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Knob_2.Limits = [0.5 2];
            app.Knob_2.MajorTicks = [0.5 0.75 1 1.25 1.5 1.75 2];
            app.Knob_2.MajorTickLabels = {'0.5', '0.75', '1', '1.25', '1.5', '1.75', '2'};
            app.Knob_2.MinorTicks = [0.5 0.75 1 1.25 1.5 1.75 2];
            app.Knob_2.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end